classdef Interfaz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Image2        matlab.ui.control.Image
        Image         matlab.ui.control.Image
        hSlider       matlab.ui.control.Slider
        hSliderLabel  matlab.ui.control.Label
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Value changed function: hSlider
        function hSliderValueChanged(app, event)
            value = app.hSlider.Value;
            %currPos = app.Image1.Position;
            %startingX = 125;
            startingY = 356;
            limitY = 151;
            % x = app.Image1.Position(1)
            % y = app.Image1.Position(2)
            app.Image.Position(2) = startingY - (startingY - limitY)*(3-value)/3;
            v = @(x) (sin(x) + 1)*3.5;
            fplot(app.UIAxes,v, [0 20], 'b->')
            title(app.UIAxes, 'Corriente de la bobina')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'V')
            R = 0.34;
            mu = pi*4*10^(-7);
            r = 0.01;
            d = value/100;
            B = @(v) (mu*2*v/R)*(r^2/(d^2 + r^2)^(3/2));
            phi = @(B) 2000*B*pi*r^2;
            tmax = 20;
            t = 0;
            delta = 0.1;
            ei = [];
            cont = 1;
            for i = t:delta:tmax
                volt1 = v(i);
                b1 = B(volt1);
                flujo1 = phi(b1);
                volt2 = v(i + delta);
                b2 = B(volt2);
                flujo2 = phi(b2);
                ei(cont) = (flujo2 - flujo1)/delta;
                cont = cont+1;
            end
            plot(app.UIAxes2, t:delta:tmax, ei, 'r-*')
            title(app.UIAxes2, "Corriente inducida"+ newline + "maxV = " + num2str(ei(1)*1000) + " mV")
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'V')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.hSliderLabel = uilabel(app.UIFigure);
            app.hSliderLabel.Text = 'h';
            app.hSlider = uislider(app.UIFigure);
            app.hSlider.Limits = [0 3];
            app.hSlider.ValueChangedFcn = createCallbackFcn(app, @hSliderValueChanged, true);
            app.hSlider.Value = 3;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Alambre con led.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Alambre pila transistor.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end